classdef newk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SAVEButton                     matlab.ui.control.Button
        FACESDETECTIONButtonGroup      matlab.ui.container.ButtonGroup
        COUNTFACESButton               matlab.ui.control.Button
        DETETCTFACESButton             matlab.ui.control.Button
        NoDetectedFacesEditField_3     matlab.ui.control.NumericEditField
        NoDetectedFacesEditField_3Label  matlab.ui.control.Label
        ShowtheithFaceEditField        matlab.ui.control.NumericEditField
        ShowtheithFaceEditFieldLabel   matlab.ui.control.Label
        LOADButton                     matlab.ui.control.Button
        COUNTFACESLabel                matlab.ui.control.Label
        FACEMASKSDETECTIONButtonGroup  matlab.ui.container.ButtonGroup
        DETETCTMASKONFACEButton        matlab.ui.control.Button
        NoDetectedFaceMasksEditField   matlab.ui.control.NumericEditField
        NoDetectedFaceMasksEditFieldLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Button                         matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ShowtheithFaceEditField
        function ShowtheithFaceEditFieldValueChanged(app, event)

            value = app.ShowtheithFaceEditField.Value;
            global facesLocations;
            global detectedFaces;
            global I;
            if value>=1
                if value<=size(facesLocations,1)
                    detectedFaces = imcrop(I,facesLocations(value,:));
                   imshow(detectedFaces,'Parent',app.UIAxes) 
                end
            end
        end

        % Value changed function: NoDetectedFacesEditField_3
        function NoDetectedFacesEditField_3ValueChanged(app, event)
            value = app.NoDetectedFacesEditField_3.Value;
            global detectedFaces;
            [file,path] = uiputfile({'.jpg';'.png'},'Save Image As');
            if file == 0
                return;
            else
                filename = [path,file];
                imwrite(detectedFaces,filename);
            end
        end

        % Value changed function: NoDetectedFaceMasksEditField
        function NoDetectedFaceMasksEditFieldValueChanged(app, event)
       value = app.NoDetectedFaceMasks.Value;
            
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            global I;
           [filename,filepath] = uigetfile({'.;.jpg;.png;*'}, 'Select An Image');
           fullname = [filepath, filename];
           I = imread(fullname);
           app.Image.ImageSource = fullname;
        end

        % Button pushed function: DETETCTFACESButton
        function DETETCTFACESButtonPushed(app, event)
            global I;
            global detectedFaces;
            global facesLocations;
            
            face = vision.CascadeObjectDetector();
            face.MergeThreshold = 4;
            facesLocations = face(I);
            label = [1:size(facesLocations,1)];
            detectedFaces = insertObjectAnnotation(I,'rectangle',facesLocations,label,'LineWidth',10,'TextColor','black','FontSize',25,'TextBoxOpacity',0.9);
            
            imshow(detectedFaces,'Parent',app.UIAxes)
        end

        % Button pushed function: COUNTFACESButton
        function COUNTFACESButtonPushed(app, event)
            global facesLocations;
            app.NoDetectedFacesEditField_3.Value = size(facesLocations,1);
        end

        % Button pushed function: DETETCTMASKONFACEButton
        function DETETCTMASKONFACEButtonPushed(app, event)
            global facesLocations;
            global detectedFaces;
            global I;
            nbMasks =0;
            for i=1:size(facesLocations,1)
                imFace=imcrop(I,facesLocations(i,:));
                
                nose = vision.CascadeObjectDetector('Nose');
                nose.MergeThreshold =100;
                noseLocations = step(nose,imFace);
                mouth = vision.CascadeObjectDetector('Mouth');
                mouth.MergeThreshold = 200;
                mouthLocations = step(mouth,imFace);
                
                
                if size(mouthLocations,1)==0  && size(noseLocations,1)==0
                    nbMasks = nbMasks + 1; 
                end
            end
            app.NoDetectedFaceMasksEditField.Value = nbMasks;
         
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            global detectedFaces;
            [file,path] = uiputfile({'.jpg';'.png'},'Save Image As');
            if file == 0
                return;
            else
                filename = [path,file];
                imwrite(detectedFaces,filename);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = '14410-dark-orange-abstract-background.jpg';
            app.Button.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.FACEMASKSDETECTIONButtonGroup = uibuttongroup(app.UIFigure);
            app.FACEMASKSDETECTIONButtonGroup.Title = 'FACE MASKS DETECTION';
            app.NoDetectedFaceMasksEditFieldLabel = uilabel(app.FACEMASKSDETECTIONButtonGroup);
            app.NoDetectedFaceMasksEditFieldLabel.Text = 'NoDetectedFaceMasks';
            app.NoDetectedFaceMasksEditField = uieditfield(app.FACEMASKSDETECTIONButtonGroup, 'numeric');
            app.NoDetectedFaceMasksEditField.ValueChangedFcn = createCallbackFcn(app, @NoDetectedFaceMasksEditFieldValueChanged, true);
            app.DETETCTMASKONFACEButton = uibutton(app.FACEMASKSDETECTIONButtonGroup, 'push');
            app.DETETCTMASKONFACEButton.ButtonPushedFcn = createCallbackFcn(app, @DETETCTMASKONFACEButtonPushed, true);
            app.DETETCTMASKONFACEButton.Text = 'DETETCT MASK ON FACE';
            app.COUNTFACESLabel = uilabel(app.UIFigure);
            app.COUNTFACESLabel.Text = 'COUNT FACES';
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.FACESDETECTIONButtonGroup = uibuttongroup(app.UIFigure);
            app.FACESDETECTIONButtonGroup.Title = 'FACES DETECTION';
            app.ShowtheithFaceEditFieldLabel = uilabel(app.FACESDETECTIONButtonGroup);
            app.ShowtheithFaceEditFieldLabel.Text = 'Show the ith Face';
            app.ShowtheithFaceEditField = uieditfield(app.FACESDETECTIONButtonGroup, 'numeric');
            app.ShowtheithFaceEditField.ValueChangedFcn = createCallbackFcn(app, @ShowtheithFaceEditFieldValueChanged, true);
            app.NoDetectedFacesEditField_3Label = uilabel(app.FACESDETECTIONButtonGroup);
            app.NoDetectedFacesEditField_3Label.Text = 'No.Detected Faces';
            app.NoDetectedFacesEditField_3 = uieditfield(app.FACESDETECTIONButtonGroup, 'numeric');
            app.NoDetectedFacesEditField_3.ValueChangedFcn = createCallbackFcn(app, @NoDetectedFacesEditField_3ValueChanged, true);
            app.DETETCTFACESButton = uibutton(app.FACESDETECTIONButtonGroup, 'push');
            app.DETETCTFACESButton.ButtonPushedFcn = createCallbackFcn(app, @DETETCTFACESButtonPushed, true);
            app.DETETCTFACESButton.Text = 'DETETCT FACES';
            app.COUNTFACESButton = uibutton(app.FACESDETECTIONButtonGroup, 'push');
            app.COUNTFACESButton.ButtonPushedFcn = createCallbackFcn(app, @COUNTFACESButtonPushed, true);
            app.COUNTFACESButton.Text = 'COUNT FACES';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end